classdef Simulacion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SimulacionGet                 matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        TaylorTab                     matlab.ui.container.Tab
        IngreseelgradodelpolinomioLabel  matlab.ui.control.Label
        IngreseelvalorcercanoLabel    matlab.ui.control.Label
        IngreselafuncionLabel         matlab.ui.control.Label
        IngreseelvaloraevaluarLabel   matlab.ui.control.Label
        ValorTaylor                   matlab.ui.control.NumericEditField
        GradoTaylor                   matlab.ui.control.NumericEditField
        CercanoTaylor                 matlab.ui.control.NumericEditField
        FuncionTaylor                 matlab.ui.control.EditField
        IngreselosvaloresrequeridosTaylor  matlab.ui.control.Label
        ObtenerpolinomioButtonTaylor  matlab.ui.control.Button
        Diferencias2Tab               matlab.ui.container.Tab
        GenerarvaloresButtonDiferencias  matlab.ui.control.Button
        NDiferencias                  matlab.ui.control.NumericEditField
        IngreseNEditFieldLabel        matlab.ui.control.Label
        yfLabel                       matlab.ui.control.Label
        YfDiferencias                 matlab.ui.control.NumericEditField
        EqualDif02                    matlab.ui.control.Label
        XfDiferencias                 matlab.ui.control.NumericEditField
        Y0Diferencias                 matlab.ui.control.NumericEditField
        EqualDif01                    matlab.ui.control.Label
        X0Diferencias                 matlab.ui.control.NumericEditField
        yLabel                        matlab.ui.control.Label
        IngreselosvaloresrequeridosDiferencias  matlab.ui.control.Label
        FuncionDiferencias            matlab.ui.control.EditField
        IngreselafuncionLabel_2       matlab.ui.control.Label
        Nolineal2x2Tab                matlab.ui.container.Tab
        GenerarButtonNoLineal         matlab.ui.control.Button
        NumerodeiteracionesNoLineal   matlab.ui.control.NumericEditField
        NumerodeiteracionesEditFieldLabel  matlab.ui.control.Label
        Valorinicial2NoLineal         matlab.ui.control.NumericEditField
        Valorinicial2EditFieldLabel   matlab.ui.control.Label
        Valorinicial1NoLineal         matlab.ui.control.NumericEditField
        Valorinicial1EditFieldLabel   matlab.ui.control.Label
        IngreselafuncionLabel_4       matlab.ui.control.Label
        Funcion2NoLineal              matlab.ui.control.EditField
        IngreselafuncionLabel_3       matlab.ui.control.Label
        Funcion1NoLineal              matlab.ui.control.EditField
        IngreselosvaloresrequeridosNoLineal  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function ObtenerpolinomioButtonPushed(app, event)
            functionTaylorText = app.FuncionTaylor.Value;
            gradoTaylorText = app.GradoTaylor.Value;
            cercanoTaylorText = app.CercanoTaylor.Value;
            valorTaylorText = app.ValorTaylor.Value;
            try
                generateTaylor(functionTaylorText, 'x', gradoTaylorText, cercanoTaylorText, valorTaylorText);
            catch ME
                errordlg(sprintf('An error occurred: %s', ME.message), 'Error');
            end
        end

        % Button pushed function: GenerarvaloresButtonDiferencias
        function GenerarvaloresButtonDiferenciasPushed(app, event)
            functionDiferenciasText = app.FuncionDiferencias.Value;
            x0DiferenciasText = app.X0Diferencias.Value;
            y0DiferenciasText = app.Y0Diferencias.Value;
            xfDiferenciasText = app.XfDiferencias.Value;
            yfDiferenciasText = app.YfDiferencias.Value;
            nDiferenciasText = app.NDiferencias.Value;
            try
                generateFinite(functionDiferenciasText, x0DiferenciasText, y0DiferenciasText, xfDiferenciasText, yfDiferenciasText, nDiferenciasText);
            catch ME
                errordlg(sprintf('An error occurred: %s', ME.message), 'Error');
            end
        end

        % Button pushed function: GenerarButtonNoLineal
        function GenerarButtonNoLinealPushed(app, event)
            funcionNoLinealText{1} = app.Funcion1NoLineal.Value;
            funcionNoLinealText{2} = app.Funcion2NoLineal.Value;
            inicialNoLinealText{1} = app.Valorinicial1NoLineal.Value;
            inicialNoLinealText{2} = app.Valorinicial2NoLineal.Value;
            iteracionesNoLinealText = app.NumerodeiteracionesNoLineal.Value;
            %try
                generateNoLineal(funcionNoLinealText, inicialNoLinealText, iteracionesNoLinealText);
            %catch ME
            %    errordlg(sprintf('An error occurred: %s', ME.message), 'Error');
            %end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SimulacionGet = uifigure('Visible', 'off');
            app.SimulacionGet.Name = 'Taylor';
            app.TabGroup = uitabgroup(app.SimulacionGet);
            app.TaylorTab = uitab(app.TabGroup);
            app.TaylorTab.Title = 'Taylor';
            app.ObtenerpolinomioButtonTaylor = uibutton(app.TaylorTab, 'push');
            app.ObtenerpolinomioButtonTaylor.Text = 'Obtener polinomio';
            app.IngreselosvaloresrequeridosTaylor = uilabel(app.TaylorTab);
            app.IngreselosvaloresrequeridosTaylor.Text = 'Ingrese los valores requeridos';
            app.FuncionTaylor = uieditfield(app.TaylorTab, 'text');
            app.CercanoTaylor = uieditfield(app.TaylorTab, 'numeric');
            app.GradoTaylor = uieditfield(app.TaylorTab, 'numeric');
            app.ValorTaylor = uieditfield(app.TaylorTab, 'numeric');
            app.IngreseelvaloraevaluarLabel = uilabel(app.TaylorTab);
            app.IngreseelvaloraevaluarLabel.Text = 'Ingrese el valor a evaluar';
            app.IngreselafuncionLabel = uilabel(app.TaylorTab);
            app.IngreselafuncionLabel.Text = 'Ingrese la funcion';
            app.IngreseelvalorcercanoLabel = uilabel(app.TaylorTab);
            app.IngreseelvalorcercanoLabel.Text = 'Ingrese el valor cercano';
            app.IngreseelgradodelpolinomioLabel = uilabel(app.TaylorTab);
            app.IngreseelgradodelpolinomioLabel.Text = 'Ingrese el grado del polinomio';
            app.Diferencias2Tab = uitab(app.TabGroup);
            app.Diferencias2Tab.Title = 'Diferencias (2)';
            app.IngreselafuncionLabel_2 = uilabel(app.Diferencias2Tab);
            app.IngreselafuncionLabel_2.Text = 'Ingrese la funcion';
            app.FuncionDiferencias = uieditfield(app.Diferencias2Tab, 'text');
            app.IngreselosvaloresrequeridosDiferencias = uilabel(app.Diferencias2Tab);
            app.IngreselosvaloresrequeridosDiferencias.Text = 'Ingrese los valores requeridos en esta sintaxis, (maximo orden 2): y'''' = (p(x))y'' + (g(x))y + (r(x))';
            app.yLabel = uilabel(app.Diferencias2Tab);
            app.yLabel.Text = 'y0';
            app.X0Diferencias = uieditfield(app.Diferencias2Tab, 'numeric');
            app.EqualDif01 = uilabel(app.Diferencias2Tab);
            app.EqualDif01.Text = '=';
            app.Y0Diferencias = uieditfield(app.Diferencias2Tab, 'numeric');
            app.XfDiferencias = uieditfield(app.Diferencias2Tab, 'numeric');
            app.EqualDif02 = uilabel(app.Diferencias2Tab);
            app.EqualDif02.Text = '=';
            app.YfDiferencias = uieditfield(app.Diferencias2Tab, 'numeric');
            app.yfLabel = uilabel(app.Diferencias2Tab);
            app.yfLabel.Text = 'yf';
            app.IngreseNEditFieldLabel = uilabel(app.Diferencias2Tab);
            app.IngreseNEditFieldLabel.Text = 'Ingrese N';
            app.NDiferencias = uieditfield(app.Diferencias2Tab, 'numeric');
            app.GenerarvaloresButtonDiferencias = uibutton(app.Diferencias2Tab, 'push');
            app.GenerarvaloresButtonDiferencias.ButtonPushedFcn = createCallbackFcn(app, @GenerarvaloresButtonDiferenciasPushed, true);
            app.GenerarvaloresButtonDiferencias.Text = 'Generar valores';
            app.Nolineal2x2Tab = uitab(app.TabGroup);
            app.Nolineal2x2Tab.Title = 'No lineal 2 x 2';
            app.IngreselosvaloresrequeridosNoLineal = uilabel(app.Nolineal2x2Tab);
            app.IngreselosvaloresrequeridosNoLineal.Text = 'Ingrese los valores requeridos';
            app.Funcion1NoLineal = uieditfield(app.Nolineal2x2Tab, 'text');
            app.IngreselafuncionLabel_3 = uilabel(app.Nolineal2x2Tab);
            app.IngreselafuncionLabel_3.Text = 'Ingrese la funcion 1';
            app.Funcion2NoLineal = uieditfield(app.Nolineal2x2Tab, 'text');
            app.IngreselafuncionLabel_4 = uilabel(app.Nolineal2x2Tab);
            app.IngreselafuncionLabel_4.Text = 'Ingrese la funcion 2';
            app.Valorinicial1EditFieldLabel = uilabel(app.Nolineal2x2Tab);
            app.Valorinicial1EditFieldLabel.Text = 'Valor inicial 1';
            app.Valorinicial1NoLineal = uieditfield(app.Nolineal2x2Tab, 'numeric');
            app.Valorinicial2EditFieldLabel = uilabel(app.Nolineal2x2Tab);
            app.Valorinicial2EditFieldLabel.Text = 'Valor inicial 2';
            app.Valorinicial2NoLineal = uieditfield(app.Nolineal2x2Tab, 'numeric');
            app.NumerodeiteracionesEditFieldLabel = uilabel(app.Nolineal2x2Tab);
            app.NumerodeiteracionesEditFieldLabel.Text = 'Numero de iteraciones';
            app.NumerodeiteracionesNoLineal = uieditfield(app.Nolineal2x2Tab, 'numeric');
            app.GenerarButtonNoLineal = uibutton(app.Nolineal2x2Tab, 'push');
            app.GenerarButtonNoLineal.ButtonPushedFcn = createCallbackFcn(app, @GenerarButtonNoLinealPushed, true);
            app.GenerarButtonNoLineal.Text = 'Generar';
            % Show the figure after all components are created
            app.SimulacionGet.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Simulacion

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SimulacionGet)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SimulacionGet)
        end
    end
end